classdef InCA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        NewVideoMenu                    matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        SaveDataMenu                    matlab.ui.container.Menu
        LoadSideBySideVideoMenu         matlab.ui.container.Menu
        ExporttoExcelMenu               matlab.ui.container.Menu
        BubbleDetectionMenu             matlab.ui.container.Menu
        StartDetectionMenu              matlab.ui.container.Menu
        IgnoreFirstFrameMenu            matlab.ui.container.Menu
        MultibubbleAnalysisMenu         matlab.ui.container.Menu
        RunDetectionBothWaysMenu        matlab.ui.container.Menu
        FrameIgnoreWarningMenu          matlab.ui.container.Menu
        PreProcessFramesMenu            matlab.ui.container.Menu
        ChangePreprocessingSettingsMenu  matlab.ui.container.Menu
        BubbleAnalysisMenu              matlab.ui.container.Menu
        StartAnalysisMenu               matlab.ui.container.Menu
        FitFourierSeriesToPointsMenu    matlab.ui.container.Menu
        ChangeFourierFitSettingsMenu    matlab.ui.container.Menu
        RefreshFourierPlotsMenu         matlab.ui.container.Menu
        CameraSettingsMenu              matlab.ui.container.Menu
        IMRMenu                         matlab.ui.container.Menu
        ExportCurrentAnalysisDirectlytoIMRMenu  matlab.ui.container.Menu
        SaveCurrentAnalysisinanIMRDataFileMenu  matlab.ui.container.Menu
        CompileMultpleIMRDataSetsMenu   matlab.ui.container.Menu
        IncludeCurrentAnalysisinCompilationMenu  matlab.ui.container.Menu
        ChangeExportSettingsMenu        matlab.ui.container.Menu
        ThemesMenu                      matlab.ui.container.Menu
        ChangeThemeMenu                 matlab.ui.container.Menu
        UseCustomThemeMenu              matlab.ui.container.Menu
        ControlPanel                    matlab.ui.container.Panel
        BeginBubbleDetectionButton      matlab.ui.control.Button
        BeginBubbleAnalysisButton       matlab.ui.control.Button
        ControlPanelTabs                matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        LoadNewVideoButton              matlab.ui.control.Button
        SaveDataButton                  matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        PathEditFieldLabel              matlab.ui.control.Label
        VideoPath                       matlab.ui.control.EditField
        LoadPath                        matlab.ui.control.EditField
        SavePath                        matlab.ui.control.EditField
        DetectionTab                    matlab.ui.container.Tab
        IgnoreFirstFrameCheckBox        matlab.ui.control.CheckBox
        MultibubbleAnalysisCheckBox     matlab.ui.control.CheckBox
        FramePreprocessingMethodButtonGroup  matlab.ui.container.ButtonGroup
        SharpenButton                   matlab.ui.control.RadioButton
        SoftenButton                    matlab.ui.control.RadioButton
        FilterStrengthSpinnerLabel      matlab.ui.control.Label
        FilterStrengthSpinner           matlab.ui.control.Spinner
        PreProcessFramesCheckBox        matlab.ui.control.CheckBox
        RunDetectionBothWaysCheckBox    matlab.ui.control.CheckBox
        FrameIgnoreWarningCheckBox      matlab.ui.control.CheckBox
        AnalysisTab                     matlab.ui.container.Tab
        MinArcLengthSpinnerLabel        matlab.ui.control.Label
        MinArcLengthSpinner             matlab.ui.control.Spinner
        FitFourierSeriestoPointsCheckBox  matlab.ui.control.CheckBox
        NumberofTermsinFitButtonGroup   matlab.ui.container.ButtonGroup
        FixedButton                     matlab.ui.control.RadioButton
        ArcLengthdependentButton        matlab.ui.control.RadioButton
        MaxNumberofTermsEditFieldLabel  matlab.ui.control.Label
        MaxNumberofTermsEditField       matlab.ui.control.NumericEditField
        TermstoPlotEditFieldLabel       matlab.ui.control.Label
        TermstoPlotEditField            matlab.ui.control.NumericEditField
        CameraSettingsTab               matlab.ui.container.Tab
        MicronPixelEditFieldLabel       matlab.ui.control.Label
        MicronPixelEditField            matlab.ui.control.NumericEditField
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.NumericEditField
        PlotAxesSettingsButtonGroup     matlab.ui.container.ButtonGroup
        PxFrameButton                   matlab.ui.control.RadioButton
        MicronSecondsButton             matlab.ui.control.RadioButton
        UpdateButton                    matlab.ui.control.Button
        IMRTab                          matlab.ui.container.Tab
        ExportDataDirectlytoIMRButton   matlab.ui.control.Button
        NumberFourierTermstoExportEditFieldLabel  matlab.ui.control.Label
        NumberFourierTermstoExportEditField  matlab.ui.control.NumericEditField
        SaveIMRDatatoFileButton         matlab.ui.control.Button
        CompileMultipleIMRDataSetsButton  matlab.ui.control.Button
        IncludeCurrentAnalysisCheckBox  matlab.ui.control.CheckBox
        ThemesTab                       matlab.ui.container.Tab
        OptionsButtonGroup              matlab.ui.container.ButtonGroup
        DefaultButton                   matlab.ui.control.RadioButton
        NightButton                     matlab.ui.control.RadioButton
        DarkButton                      matlab.ui.control.RadioButton
        ApplyButton                     matlab.ui.control.Button
        ArmyButton                      matlab.ui.control.RadioButton
        LeatherButton                   matlab.ui.control.RadioButton
        WavesButton                     matlab.ui.control.RadioButton
        CustomButton                    matlab.ui.control.RadioButton
        RePlotFourierDataButton         matlab.ui.control.Button
        ExporttoExcelButton             matlab.ui.control.Button
        MaskOverlayPreviewPanel         matlab.ui.container.Panel
        MainViewerPanel                 matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        ViewerTab                       matlab.ui.container.Tab
        MainPlot                        matlab.ui.control.UIAxes
        PreviousFrameButton             matlab.ui.control.Button
        NextFrameButton                 matlab.ui.control.Button
        AreaLabel                       matlab.ui.control.Label
        PerimeterLabel                  matlab.ui.control.Label
        AverageRadiusLabel              matlab.ui.control.Label
        CentroidLabel                   matlab.ui.control.Label
        FrameNumberSpinnerLabel         matlab.ui.control.Label
        FrameNumberSpinner              matlab.ui.control.Spinner
        EvolutionOverlayTab             matlab.ui.container.Tab
        EvolutionPlot                   matlab.ui.control.UIAxes
        ColorMap                        matlab.ui.control.Image
        Frame1Label                     matlab.ui.control.Label
        Frame180Label                   matlab.ui.control.Label
        AnalysisResultsPanel            matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        DTab                            matlab.ui.container.Tab
        AverageRadiusPanel              matlab.ui.container.Panel
        RadiusPlot                      matlab.ui.control.UIAxes
        PerimeterPanel                  matlab.ui.container.Panel
        PerimeterPlot                   matlab.ui.control.UIAxes
        AreaPanel                       matlab.ui.container.Panel
        AreaPlot                        matlab.ui.control.UIAxes
        DTab_2                          matlab.ui.container.Tab
        SurfaceAreaPanel                matlab.ui.container.Panel
        SurfaceAreaPlot                 matlab.ui.control.UIAxes
        VolumePanel                     matlab.ui.container.Panel
        VolumePlot                      matlab.ui.control.UIAxes
        CentroidTab                     matlab.ui.container.Tab
        CentroidPlot                    matlab.ui.control.UIAxes
        FourierFitDataTab               matlab.ui.container.Tab
        AsphericityPlot                 matlab.ui.control.UIAxes
        FourierColorMap                 matlab.ui.control.Image
        ndTermLabel                     matlab.ui.control.Label
        LastTermLabel                   matlab.ui.control.Label
        FourierDecompositionTab         matlab.ui.container.Tab
        DecompositionSettingsPanel      matlab.ui.container.Panel
        TargetFrameEditFieldLabel       matlab.ui.control.Label
        TargetFrameEditField            matlab.ui.control.NumericEditField
        TermstoDecomposeEditFieldLabel  matlab.ui.control.Label
        TermstoDecomposeEditField       matlab.ui.control.NumericEditField
        SortOrderButtonGroup            matlab.ui.container.ButtonGroup
        DescendingButton                matlab.ui.control.RadioButton
        AscendingButton                 matlab.ui.control.RadioButton
        MaintainColormapCheckBox        matlab.ui.control.CheckBox
        DecomposeButton                 matlab.ui.control.Button
        DecomposedPlotsPanel            matlab.ui.container.Panel
    end

    
    properties (Access = public)
        %Main data
        frames 
        mask
        maskInformation
        videoPath
        ignoreFrames
        currentFrame
        radius 
        area
        perimeter
        surfaceArea
        centroid
        volume
        
        %Boolean values
        ignoreFirstFrame
        multibubbleAnalysis
        runDetectionBothWays
        frameIgnoreWarning
        preProcessFrames
        fitFourierSeriesToPoints
        
        %User Input valuse
        preProcessingMethod
        preProcessingStrength
        minArcLength
        numberOfTermsinFitType
        maxNumberOfTerms
        termsToPlot
        numFrames
        convertedPlotSet
        frameInterval
        mpxRatio
        fps
        plotAxesSetting
        numberFourierTermsToExport
        includeCurrentAnalysisinExport
        theme
        colorMap   
    end
    
    methods (Access = private)
        
        function rickRoll(~)
            figureui = uifigure("WindowState", "maximized");
            figureui.Name = "Why did you press that button";
            drawnow;
            pause('on');
            pause(0.02);
            position = figureui.Position;
            html = uihtml(figureui, "Position", [0, 0, position(3), position(4)]);
            html.HTMLSource = fullfile(pwd, '/main/test.htm');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Visible = 'off';
            drawnow; %It seems we need to pause execution here otherwise cefWindowList won't have the UIFigure in it yet
            % Find the CEF window for the running app's figure: this idea
            % comes from DesktopFigureService MALTAB internal class (courtesy)
            cefWindowList = matlab.internal.webwindowmanager.instance.windowList;
            url = matlab.ui.internal.FigureServices.getFigureURL(app.UIFigure);
            [idx] = arrayfun(@(ww)strcmp(url,ww.URL), cefWindowList);
            hWin = cefWindowList(idx);
            if isdeployed % Executable in stand-alone mode.
                hWin.Icon = which('logo_ico.ico');
            else
                addpath('InCA\')
                addpath('InCA\main\');
                addpath('InCA\logs\');
                addpath('InCA\themes\');
                addpath('InCA\images\');
                hWin.Icon = which('logo_ico.ico');
            end
            app.numberFourierTermsToExport = app.NumberFourierTermstoExportEditField.Value;
            app.currentFrame = 1;
%             checkTheme(app);
%             setTheme(app, "night");
            setTheme(app, "default");
            app.UIFigure.Visible = 'on';
        end

        % Callback function: LoadNewVideoButton, NewVideoMenu
        function LoadNewVideoButtonPushed(app, event)
            resetFunction(app);
            [app.frames, vidPath, vidFile] = loadFrames();
            figure(app.UIFigure);
            if ~isempty(vidPath) && ~isempty(vidFile)
                fullpath = append(vidPath, vidFile);
                app.VideoPath.Value = fullpath;
                app.videoPath = fullpath;
                [~, ~, app.numFrames] = size(app.frames);
            end
        end

        % Callback function: BeginBubbleDetectionButton, 
        % StartDetectionMenu
        function BeginBubbleDetectionButtonPushed(app, event)
            clear app.ignoreFrames;
            clear app.mask;
            if app.IgnoreFirstFrameCheckBox.Value
                app.ignoreFrames(length(app.ignoreFrames) + 1) = 1;
            end
            if app.PreProcessFramesCheckBox.Value
                detectionFrames = preprocessFrames(app, app.frames);
            else
                detectionFrames = app.frames;
            end
            if app.RunDetectionBothWaysCheckBox.Value
                forwardMask = bubbleIsolation(app, detectionFrames);
                reverseMask = bubbleIsolationReverse(app, detectionFrames);
                f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Comparing masks...", "Indeterminate","on");
                app.mask = compareMasks(forwardMask, reverseMask);
                close(f)
            else
                app.mask = bubbleIsolation(app, detectionFrames);
            end
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Generating frame previews...", "Indeterminate","on");
            generatePreviews(app, app.frames, app.mask);
            close(f);
            uialert(app.UIFigure, "Bubble detection complete! Please review frames before proceeding. Click on the image to add it to the list of frames to ignore", 'Message', 'Icon', 'info');
        end

        % Button pushed function: BeginBubbleAnalysisButton
        function BeginBubbleAnalysisButtonPushed(app, event)
            clear app.maskInformation
            if app.FixedButton.Value == true
                adaptiveTerms = false;
            else
                adaptiveTerms = true;
            end
            app.maskInformation = bubbleTrack(app, app.mask, app.MinArcLengthSpinner.Value, app.FitFourierSeriestoPointsCheckBox.Value, ...
                app.MaxNumberofTermsEditField.Value, adaptiveTerms, app.ignoreFrames);
            [app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid] = generatePlotData(app);
            displayFrameInView(app);
            app.convertedPlotSet = convertUnits(app);
            if app.PxFrameButton.Value
                plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
                dispEvolution(app);
                plotFourier(app);
            elseif app.MicronSecondsButton.Value
                plotConvertedData(app);
                plotConvertedFourier(app);
                dispEvolution(app);
            end
        end

        % Button pushed function: NextFrameButton
        function NextFrameButtonPushed(app, event)
            if app.currentFrame == app.numFrames
                app.currentFrame = 1;
            else
                app.currentFrame = app.currentFrame + 1;
            end
            displayFrameInView(app);
            if app.PxFrameButton.Value
                plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
            else
                plotConvertedData(app)
            end
        end

        % Button pushed function: PreviousFrameButton
        function PreviousFrameButtonPushed(app, event)
            if app.currentFrame == 1
                app.currentFrame = app.numFrames;
            else
                app.currentFrame = app.currentFrame - 1;
            end
            displayFrameInView(app);
            if app.PxFrameButton.Value
                plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
            else
                plotConvertedData(app);
            end
        end

        % Callback function: LoadDataButton, LoadDataMenu
        function LoadDataButtonPushed(app, event)
            w = uiprogressdlg(app.UIFigure, "Title", 'Please wait', 'Message', 'Loading data...', 'Indeterminate',"on");
            [app, fullLoadPath] = readFromFile(app);
            figure(app.UIFigure);
            generatePreviews(app, app.frames, app.mask)
            displayFrameInView(app);
            plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
            sizes = zeros(1, app.numFrames);
            for i = 1:app.numFrames
                sizes(i) = length(app.maskInformation(i).xData);
            end
            maxSize = max(sizes);
            if maxSize > 1
                app.FitFourierSeriestoPointsCheckBox.Value = 1;
            end
            app.convertedPlotSet = convertUnits(app);
            if app.PxFrameButton.Value
                plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
                dispEvolution(app);
                plotFourier(app);
            elseif app.MicronSecondsButton.Value
                plotConvertedData(app);
                plotConvertedFourier(app);
                dispEvolution(app);
            end
            if ~isempty(fullLoadPath)
                app.LoadPath.Value = fullLoadPath;
            end
            FPSEditFieldValueChanged(app, event);
            close(w);
        end

        % Callback function: SaveDataButton, SaveDataMenu
        function SaveDataButtonPushed(app, event)
            f = uiprogressdlg(app.UIFigure, "Title", "Please wait", 'Message', 'Saving...', "Indeterminate", "on");
            savePath = writeToFile(app);
            if ~isempty(savePath)
                app.SavePath.Value = savePath;
            end
            close(f);
            uialert(app.UIFigure, "Save complete!", 'Message', 'Icon', "success");
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Changing theme...", "Indeterminate","on");
            checkTheme(app);
            close(f);
        end

        % Callback function: RePlotFourierDataButton, 
        % RefreshFourierPlotsMenu
        function RePlotFourierDataButtonPushed(app, event)
            if app.PxFrameButton.Value
                plotFourier(app);
            elseif app.MicronSecondsButton.Value
                plotConvertedFourier(app);
            end
        end

        % Button pushed function: DecomposeButton
        function DecomposeButtonPushed(app, event)
            if app.DescendingButton.Value == true
                sortOrder = "descending";
            else
                sortOrder = "ascending";
            end
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Decomposing", "Indeterminate","on");
            decompPlots = fourierDecomposition(app.maskInformation, app.TargetFrameEditField.Value, app.TermstoDecomposeEditField.Value, sortOrder);
            f.Title = "Plotting...";
            plotDecomposition(app, decompPlots);
            close(f);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            disp(key);
            switch key
                case 'f1'
                    LoadNewVideoButtonPushed(app, event);
                case 'f2'
                    LoadDataButtonPushed(app, event);
                case 'f3'
                    SaveDataButtonPushed(app, event);
                case 'f4'
                    BeginBubbleDetectionButtonPushed(app, event);
                case 'f5'
                    RePlotFourierDataButtonPushed(app, event);
                case 'f6'
                    BeginBubbleAnalysisButtonPushed(app, event);
                case 'f7'
                    ExporttoExcelButtonPushed(app, event)
                case 'f8'
                    if app.IgnoreFirstFrameCheckBox.Value == 1
                        app.IgnoreFirstFrameCheckBox.Value = 0;
                    else
                        app.IgnoreFirstFrameCheckBox.Value = 1;
                    end
                case 'f9'
                    if app.MultibubbleAnalysisCheckBox.Value == 1
                        app.MultibubbleAnalysisCheckBox.Value = 0;
                    else
                        app.MultibubbleAnalysisCheckBox.Value = 1;
                    end
                case 'f10'
                    if app.RunDetectionBothWaysCheckBox.Value == 1
                        app.RunDetectionBothWaysCheckBox.Value = 0;
                    else
                        app.RunDetectionBothWaysCheckBox.Value = 1;
                    end
                case 'f11'
                    if app.PreProcessFramesCheckBox.Value == 1
                        app.PreProcessFramesCheckBox.Value = 0;
                    else
                        app.PreProcessFramesCheckBox.Value = 1;
                    end
                case 'f12'
                    if app.FitFourierSeriestoPointsCheckBox.Value == 1
                        app.FitFourierSeriestoPointsCheckBox.Value = 0;
                    else
                        app.FitFourierSeriestoPointsCheckBox.Value = 1;
                    end
                case 'backslash'
                    launchLogViewer();
                case 'home'
                    rickRoll(app);
                case 'rightarrow'
                    NextFrameButtonPushed(app, event);
                case 'leftarrow'
                    PreviousFrameButtonPushed(app, event);
            end
        end

        % Value changed function: FrameNumberSpinner
        function FrameNumberSpinnerValueChanged(app, event)
            value = app.FrameNumberSpinner.Value;
            app.currentFrame = value;
            displayFrameInView(app);
            plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
        end

        % Callback function: ExporttoExcelButton, ExporttoExcelMenu
        function ExporttoExcelButtonPushed(app, event)
            exportToExcel(app, app.ignoreFrames);
            uialert(app.UIFigure, "Export complete!", 'Message', 'Icon', "success");
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            
            %Analysis Panel Resizing
            app.AnalysisResultsPanel.Position = [10, 5, 620./1700.*position(3), position(4) - 10];
            panelPos = app.AnalysisResultsPanel.Position;
            app.TabGroup.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            tabPos = app.TabGroup.Position;
            tabWidth = tabPos(3);
            app.CentroidPlot.Position = [0, 0, tabPos(3), tabPos(4) - 30];
            app.ndTermLabel.Position = [15, 15, 66, 22];
            app.FourierColorMap.Position = [15, 50, tabWidth - 30, 30];
            app.LastTermLabel.Position = [tabWidth - 15 - 67, 15, 67, 22];
            app.AsphericityPlot.Position = [0, 100, tabWidth - 10, tabPos(4) - 130];
            app.DecomposedPlotsPanel.Position = [0, 0, tabWidth, tabPos(4) - 130];
            app.DecompositionSettingsPanel.Position = [0, tabPos(4) - 120, tabWidth, (tabPos(4) - 25) - 10 - (tabPos(4) - 130)];
            app.PerimeterPanel.Position = [0, 0, tabWidth, (tabPos(4) - 25)./3 - 20];
            app.AreaPanel.Position = [0, tabPos(4)./3, tabWidth, (tabPos(4) - 25)./3 - 20];
            app.AverageRadiusPanel.Position = [0, 2.*tabPos(4)./3, tabWidth, (tabPos(4) - 25)./3 - 20];
            app.VolumePanel.Position = [0, tabPos(4)./3, tabWidth, (tabPos(4) - 25)./3 - 20];
            app.SurfaceAreaPanel.Position = [0, 2.*tabPos(4)./3, tabWidth, (tabPos(4) - 25)./3 - 20];
            panelPos = app.AreaPanel.Position;
            app.RadiusPlot.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            app.AreaPlot.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            app.PerimeterPlot.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            app.VolumePlot.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            app.SurfaceAreaPlot.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            
            %Mask Overlay Panel Resizing 
            app.MaskOverlayPreviewPanel.Position = [(620./1700.*position(3)) + 20, 5, 390./1700.*position(3), position(4) - 305];
            
            %Control Panel Resizing
            app.ControlPanel.Position = [(620./1700.*position(3)) + 20, position(4) - 295, 390./1700.*position(3), 290];
            app.BeginBubbleAnalysisButton.Position = [15, 3, 147, 25];
            app.BeginBubbleDetectionButton.Position = [15, 43, 147, 25];
            app.ExporttoExcelButton.Position = [(390./1700.*position(3)) - 147 - 15, 3, 147, 25];
            app.RePlotFourierDataButton.Position = [(390./1700.*position(3)) - 147 - 15, 43, 147, 25];
            
            %Main Viewer Panel Resizing
            app.MainViewerPanel.Position = [(620./1700.*position(3)) + (390./1700.*position(3)) + 30, 5, (position(3) - 40) - (390./1700.*position(3)) - (620./1700.*position(3)), position(4) - 10];
            panelPos = app.MainViewerPanel.Position;
            app.TabGroup2.Position = [0, 0, panelPos(3), panelPos(4) - 20];
            app.MainPlot.Position = [0, panelPos(4)  - (panelPos(3).*(530./648)) - 45, panelPos(3), panelPos(3).*(530./648)];
            app.EvolutionPlot.Position = [0, panelPos(4)  - (panelPos(3).*(530./648)) - 45, panelPos(3), panelPos(3).*(530./648)];
            app.Frame1Label.Position = [15, 15, 58, 22];
            app.ColorMap.Position = [15, 45, panelPos(3) - 30, 30];
            app.Frame180Label.Position = [panelPos(3) - 15 - 74, 15, 74, 22];
            app.NextFrameButton.Position = [panelPos(3) - 110,4,100,28];
            app.FrameNumberSpinner.Position = [346./648.*panelPos(3),10,100,22];
            app.FrameNumberSpinnerLabel.Position = [227./648.*panelPos(3),10,104,22];
            app.AverageRadiusLabel.Position = [panelPos(3) - 226,91,216,22];
            app.CentroidLabel.Position = [panelPos(3) - 226, 52,216,22];
            
            %Control Panel Components Resizig
            panelPos = app.IMRTab.Position;
            app.IncludeCurrentAnalysisCheckBox.Position = [10, 5, 194, 22];
            app.CompileMultipleIMRDataSetsButton.Position = [10, 35, 194, 25];
            app.SaveIMRDatatoFileButton.Position = [10, 65, 194, 25];
            app.ExportDataDirectlytoIMRButton.Position = [10, 95, 194, 25];
            %app.NumberFourierTermstoExportLabel.Position = [10, panelPos(4) - 27, 187, 22];
            app.NumberFourierTermstoExportEditField.Position = [212, panelPos(4)-26, 100, 22];
            drawnow;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Updating...", "Indeterminate","on");
            app.convertedPlotSet = convertUnits(app);
            if ~isempty(app.radius)
                if app.MicronSecondsButton.Value
                    plotConvertedData(app);
                    plotConvertedFourier(app);
                    changeAxesTitles(app, 'microns');   
                elseif app.PxFrameButton.Value
                    plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
                    plotFourier(app);
                    changeAxesTitles(app, 'pixels');
                end
            end
            if ~isempty(app.numFrames)
                app.frameInterval = 1./app.FPSEditField.Value;
            end
            close(f);
        end

        % Selection changed function: PlotAxesSettingsButtonGroup
        function PlotAxesSettingsButtonGroupSelectionChanged(app, event)
            f = uiprogressdlg(app.UIFigure ,"Title", "Please wait", "Message", "Converting...", "Indeterminate","on");
            selectedButton = app.PlotAxesSettingsButtonGroup.SelectedObject;
            if selectedButton == app.PxFrameButton
                plotData(app, app.radius, app.area, app.perimeter, app.surfaceArea, app.volume, app.centroid);
                plotFourier(app);
                changeAxesTitles(app, 'pixels');
            else
                plotConvertedData(app);
                plotConvertedFourier(app);
                changeAxesTitles(app, 'microns');
            end
            close(f);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Callback function: ExportCurrentAnalysisDirectlytoIMRMenu, 
        % ExportDataDirectlytoIMRButton
        function ExportDataDirectlytoIMRButtonPushed(app, event)
            data = configureDataForIMR(app.frames, app.mask, app.numFrames, app.ignoreFrames, app.maskInformation, app.convertedPlotSet, app.numberFourierTermsToExport);
            IMR(data);
        end

        % Value changed function: FPSEditField
        function FPSEditFieldValueChanged(app, event)
            value = app.FPSEditField.Value;
            app.frameInterval = 1./value;
        end

        % Button pushed function: SaveIMRDatatoFileButton
        function SaveIMRDatatoFileButtonPushed(app, event)
            data = configureDataForIMR(app.frames, app.mask, app.numFrames, app.ignoreFrames, app.maskInformation, app.convertedPlotSet, app.numberFourierTermsToExport);
%             f = uiprogressdlg(app.UIFigure, "Title", "Please wait", 'Message', 'Saving...', "Indeterminate", "on");
%             savePath = writeToFile(app);
%             if ~isempty(savePath)
%                 app.SavePath.Value = savePath;
%             end
%             close(f);
%             uialert(app.UIFigure, "Save complete!", 'Message', 'Icon', "success");
        end

        % Menu selected function: IgnoreFirstFrameMenu
        function IgnoreFirstFrameMenuSelected(app, event)
            if app.IgnoreFirstFrameCheckBox.Value == 1
                app.IgnoreFirstFrameCheckBox.Value = 0;
                app.IgnoreFirstFrameMenu.Checked = 0;
            else
                app.IgnoreFirstFrameCheckBox.Value = 1;
                app.IgnoreFirstFrameMenu.Checked = 1;
            end
            drawnow;
        end

        % Menu selected function: MultibubbleAnalysisMenu
        function MultibubbleAnalysisMenuSelected(app, event)
            if app.MultibubbleAnalysisCheckBox.Value == 1
                app.MultibubbleAnalysisCheckBox.Value = 0;
                app.MultibubbleAnalysisMenu.Checked = 0;
            else
                app.MultibubbleAnalysisCheckBox.Value = 1;
                app.MultibubbleAnalysisMenu.Checked = 1;
            end
            drawnow;
        end

        % Menu selected function: RunDetectionBothWaysMenu
        function RunDetectionBothWaysMenuSelected(app, event)
            if app.RunDetectionBothWaysCheckBox.Value == 1
                app.RunDetectionBothWaysCheckBox.Value = 0;
                app.RunDetectionBothWaysMenu.Checked = 0;
            else
                app.RunDetectionBothWaysCheckBox.Value = 1;
                app.RunDetectionBothWaysMenu.Checked = 1;
            end
            drawnow;
        end

        % Menu selected function: PreProcessFramesMenu
        function PreProcessFramesMenuSelected(app, event)
            if app.PreProcessFramesCheckBox.Value == 1
                app.PreProcessFramesCheckBox.Value = 0;
                app.PreProcessFramesMenu.Checked = 0;
                app.preProcessFrames = 0;
            else
                app.PreProcessFramesCheckBox.Value = 1;
                app.PreProcessFramesMenu.Checked = 1;
                app.preProcessFrames = 1;
            end
            drawnow;
        end

        % Menu selected function: ChangePreprocessingSettingsMenu
        function ChangePreprocessingSettingsMenuSelected(app, event)
           menuPanel = createSettingsPanel("Frame Pre-Processing Settings", [310, 200], app.UIFigure.Position, app.theme, app.UIFigure);
           menuPanel = populatePreProcessingSettingsPanel(menuPanel, app.pre, app.FilterStrengthSpinner.Value, app.SoftenButton.Value);
           menuPanel.Visible = 'on';
        end

        % Value changed function: 
        % NumberFourierTermstoExportEditField
        function NumberFourierTermstoExportEditFieldValueChanged(app, event)
            value = app.NumberFourierTermstoExportEditField.Value;
            app.numberFourierTermsToExport = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'InCA';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewVideoMenu = uimenu(app.FileMenu);
            app.NewVideoMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadNewVideoButtonPushed, true);
            app.NewVideoMenu.Text = 'New Video';
            app.LoadDataMenu = uimenu(app.FileMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataMenu.Text = 'Load Data';
            app.SaveDataMenu = uimenu(app.FileMenu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataMenu.Text = 'Save Data';
            app.LoadSideBySideVideoMenu = uimenu(app.FileMenu);
            app.LoadSideBySideVideoMenu.Text = 'Load Side By Side Video';
            app.ExporttoExcelMenu = uimenu(app.FileMenu);
            app.ExporttoExcelMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelMenu.Text = 'Export to Excel';
            app.BubbleDetectionMenu = uimenu(app.UIFigure);
            app.BubbleDetectionMenu.Text = 'Bubble Detection';
            app.StartDetectionMenu = uimenu(app.BubbleDetectionMenu);
            app.StartDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @BeginBubbleDetectionButtonPushed, true);
            app.StartDetectionMenu.Text = 'Start Detection';
            app.IgnoreFirstFrameMenu = uimenu(app.BubbleDetectionMenu);
            app.IgnoreFirstFrameMenu.MenuSelectedFcn = createCallbackFcn(app, @IgnoreFirstFrameMenuSelected, true);
            app.IgnoreFirstFrameMenu.Checked = 'on';
            app.IgnoreFirstFrameMenu.Text = 'Ignore First Frame';
            app.MultibubbleAnalysisMenu = uimenu(app.BubbleDetectionMenu);
            app.MultibubbleAnalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @MultibubbleAnalysisMenuSelected, true);
            app.MultibubbleAnalysisMenu.Text = 'Multibubble Analysis';
            app.RunDetectionBothWaysMenu = uimenu(app.BubbleDetectionMenu);
            app.RunDetectionBothWaysMenu.MenuSelectedFcn = createCallbackFcn(app, @RunDetectionBothWaysMenuSelected, true);
            app.RunDetectionBothWaysMenu.Text = 'Run Detection Both Ways';
            app.FrameIgnoreWarningMenu = uimenu(app.BubbleDetectionMenu);
            app.FrameIgnoreWarningMenu.Checked = 'on';
            app.FrameIgnoreWarningMenu.Text = 'Frame Ignore Warning';
            app.PreProcessFramesMenu = uimenu(app.BubbleDetectionMenu);
            app.PreProcessFramesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreProcessFramesMenuSelected, true);
            app.PreProcessFramesMenu.Checked = 'on';
            app.PreProcessFramesMenu.Text = 'Pre-Process Frames';
            app.ChangePreprocessingSettingsMenu = uimenu(app.PreProcessFramesMenu);
            app.ChangePreprocessingSettingsMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangePreprocessingSettingsMenuSelected, true);
            app.ChangePreprocessingSettingsMenu.Text = 'Change Preprocessing Settings';
            app.BubbleAnalysisMenu = uimenu(app.UIFigure);
            app.BubbleAnalysisMenu.Text = 'Bubble Analysis';
            app.StartAnalysisMenu = uimenu(app.BubbleAnalysisMenu);
            app.StartAnalysisMenu.Text = 'Start Analysis';
            app.FitFourierSeriesToPointsMenu = uimenu(app.BubbleAnalysisMenu);
            app.FitFourierSeriesToPointsMenu.Checked = 'on';
            app.FitFourierSeriesToPointsMenu.Text = 'Fit Fourier Series To Points';
            app.ChangeFourierFitSettingsMenu = uimenu(app.BubbleAnalysisMenu);
            app.ChangeFourierFitSettingsMenu.Text = 'Change Fourier Fit Settings';
            app.RefreshFourierPlotsMenu = uimenu(app.BubbleAnalysisMenu);
            app.RefreshFourierPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @RePlotFourierDataButtonPushed, true);
            app.RefreshFourierPlotsMenu.Text = 'Refresh Fourier Plots';
            app.CameraSettingsMenu = uimenu(app.UIFigure);
            app.CameraSettingsMenu.Text = 'Camera Settings';
            app.IMRMenu = uimenu(app.UIFigure);
            app.IMRMenu.Text = 'IMR';
            app.ExportCurrentAnalysisDirectlytoIMRMenu = uimenu(app.IMRMenu);
            app.ExportCurrentAnalysisDirectlytoIMRMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataDirectlytoIMRButtonPushed, true);
            app.ExportCurrentAnalysisDirectlytoIMRMenu.Text = 'Export Current Analysis Directly to IMR';
            app.SaveCurrentAnalysisinanIMRDataFileMenu = uimenu(app.IMRMenu);
            app.SaveCurrentAnalysisinanIMRDataFileMenu.Text = 'Save Current Analysis in an IMR Data File';
            app.CompileMultpleIMRDataSetsMenu = uimenu(app.IMRMenu);
            app.CompileMultpleIMRDataSetsMenu.Text = 'Compile Multple IMR Data Sets';
            app.IncludeCurrentAnalysisinCompilationMenu = uimenu(app.CompileMultpleIMRDataSetsMenu);
            app.IncludeCurrentAnalysisinCompilationMenu.Checked = 'on';
            app.IncludeCurrentAnalysisinCompilationMenu.Text = 'Include Current Analysis in Compilation';
            app.ChangeExportSettingsMenu = uimenu(app.IMRMenu);
            app.ChangeExportSettingsMenu.Text = 'Change Export Settings';
            app.ThemesMenu = uimenu(app.UIFigure);
            app.ThemesMenu.Text = 'Themes';
            app.ChangeThemeMenu = uimenu(app.ThemesMenu);
            app.ChangeThemeMenu.Text = 'Change Theme';
            app.UseCustomThemeMenu = uimenu(app.ThemesMenu);
            app.UseCustomThemeMenu.Text = 'Use Custom Theme';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.BeginBubbleDetectionButton = uibutton(app.ControlPanel, 'push');
            app.BeginBubbleDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BeginBubbleDetectionButtonPushed, true);
            app.BeginBubbleDetectionButton.Tooltip = {'F4'};
            app.BeginBubbleDetectionButton.Text = 'Begin Bubble Detection';
            app.BeginBubbleAnalysisButton = uibutton(app.ControlPanel, 'push');
            app.BeginBubbleAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @BeginBubbleAnalysisButtonPushed, true);
            app.BeginBubbleAnalysisButton.Tooltip = {'F6'};
            app.BeginBubbleAnalysisButton.Text = 'Begin Bubble Analysis';
            app.ControlPanelTabs = uitabgroup(app.ControlPanel);
            app.FileTab = uitab(app.ControlPanelTabs);
            app.FileTab.Title = 'File';
            app.LoadNewVideoButton = uibutton(app.FileTab, 'push');
            app.LoadNewVideoButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewVideoButtonPushed, true);
            app.LoadNewVideoButton.Tooltip = {'Load a new video for analysis. '; ''; 'Keyboard shortcut: F1'};
            app.LoadNewVideoButton.Text = 'Load New Video';
            app.SaveDataButton = uibutton(app.FileTab, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Save the current analysis for later viewing. '; ''; 'Keyboard shortcut: F3'};
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.FileTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Load previously analyzed data for viewing. '; ''; 'Keyboard shortcut: F2'};
            app.LoadDataButton.Text = 'Load Data';
            app.PathEditFieldLabel = uilabel(app.FileTab);
            app.PathEditFieldLabel.Text = 'Path: ';
            app.VideoPath = uieditfield(app.FileTab, 'text');
            app.VideoPath.Editable = 'off';
            app.LoadPath = uieditfield(app.FileTab, 'text');
            app.LoadPath.Editable = 'off';
            app.SavePath = uieditfield(app.FileTab, 'text');
            app.SavePath.Editable = 'off';
            app.DetectionTab = uitab(app.ControlPanelTabs);
            app.DetectionTab.Title = 'Detection';
            app.IgnoreFirstFrameCheckBox = uicheckbox(app.DetectionTab);
            app.IgnoreFirstFrameCheckBox.Tooltip = {'Ignore the first frame of the video during the bubble detection and subsequent analysis.'; ''; 'Keyboard shortcut: F8'};
            app.IgnoreFirstFrameCheckBox.Text = 'Ignore First Frame';
            app.IgnoreFirstFrameCheckBox.Value = true;
            app.MultibubbleAnalysisCheckBox = uicheckbox(app.DetectionTab);
            app.MultibubbleAnalysisCheckBox.Tooltip = {'Expect multiple bubbles for each frame in the video.'; ''; 'Keyboard shortcut: F9'};
            app.MultibubbleAnalysisCheckBox.Text = 'Multibubble Analysis';
            app.FramePreprocessingMethodButtonGroup = uibuttongroup(app.DetectionTab);
            app.FramePreprocessingMethodButtonGroup.Title = 'Frame Preprocessing Method';
            app.SharpenButton = uiradiobutton(app.FramePreprocessingMethodButtonGroup);
            app.SharpenButton.Text = 'Sharpen';
            app.SharpenButton.Value = true;
            app.SoftenButton = uiradiobutton(app.FramePreprocessingMethodButtonGroup);
            app.SoftenButton.Text = 'Soften';
            app.FilterStrengthSpinnerLabel = uilabel(app.DetectionTab);
            app.FilterStrengthSpinnerLabel.Text = 'Filter Strength:';
            app.FilterStrengthSpinner = uispinner(app.DetectionTab);
            app.FilterStrengthSpinner.Limits = [1 Inf];
            app.FilterStrengthSpinner.Value = 1;
            app.PreProcessFramesCheckBox = uicheckbox(app.DetectionTab);
            app.PreProcessFramesCheckBox.Tooltip = {'Sharpen or soften edges in the frames before detection occurs.'; ''; 'Keyboard shortcut: F11'};
            app.PreProcessFramesCheckBox.Text = 'Pre-Process Frames';
            app.PreProcessFramesCheckBox.Value = true;
            app.RunDetectionBothWaysCheckBox = uicheckbox(app.DetectionTab);
            app.RunDetectionBothWaysCheckBox.Tooltip = {'Run the detection alrogithm forwards in time and backwards in time and compare the resulting masks. If disabled the analysis only runs forwards. '; ''; 'Keyboard shortcut: F10'};
            app.RunDetectionBothWaysCheckBox.Text = 'Run Detection Both Ways';
            app.FrameIgnoreWarningCheckBox = uicheckbox(app.DetectionTab);
            app.FrameIgnoreWarningCheckBox.Tooltip = {'Toggle whether or not the modal dialog box shows when selecting or de-selecting frames to ignore during bubble analysis. '; ''; 'Keyboard shortcut: NONE'};
            app.FrameIgnoreWarningCheckBox.Text = 'Frame Ignore Warning';
            app.FrameIgnoreWarningCheckBox.Value = true;
            app.AnalysisTab = uitab(app.ControlPanelTabs);
            app.AnalysisTab.Title = 'Analysis';
            app.MinArcLengthSpinnerLabel = uilabel(app.AnalysisTab);
            app.MinArcLengthSpinnerLabel.Text = 'Min Arc Length';
            app.MinArcLengthSpinner = uispinner(app.AnalysisTab);
            app.MinArcLengthSpinner.Limits = [0 Inf];
            app.MinArcLengthSpinner.Value = 3;
            app.FitFourierSeriestoPointsCheckBox = uicheckbox(app.AnalysisTab);
            app.FitFourierSeriestoPointsCheckBox.Tooltip = {'Fit a Fourier Series to the X and Y points on the perimeter of the bubble. '; ''; 'Keyboard shortcut: F12'};
            app.FitFourierSeriestoPointsCheckBox.Text = 'Fit Fourier Series to Points';
            app.NumberofTermsinFitButtonGroup = uibuttongroup(app.AnalysisTab);
            app.NumberofTermsinFitButtonGroup.Title = 'Number of Terms in Fit';
            app.FixedButton = uiradiobutton(app.NumberofTermsinFitButtonGroup);
            app.FixedButton.Text = 'Fixed';
            app.FixedButton.Value = true;
            app.ArcLengthdependentButton = uiradiobutton(app.NumberofTermsinFitButtonGroup);
            app.ArcLengthdependentButton.Text = 'Arc-Length dependent';
            app.MaxNumberofTermsEditFieldLabel = uilabel(app.AnalysisTab);
            app.MaxNumberofTermsEditFieldLabel.Text = '(Max) Number of Terms: ';
            app.MaxNumberofTermsEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.MaxNumberofTermsEditField.Limits = [0 Inf];
            app.MaxNumberofTermsEditField.Value = 40;
            app.TermstoPlotEditFieldLabel = uilabel(app.AnalysisTab);
            app.TermstoPlotEditFieldLabel.Text = 'Terms to Plot: ';
            app.TermstoPlotEditField = uieditfield(app.AnalysisTab, 'numeric');
            app.TermstoPlotEditField.Limits = [0 Inf];
            app.TermstoPlotEditField.Value = 8;
            app.CameraSettingsTab = uitab(app.ControlPanelTabs);
            app.CameraSettingsTab.Title = 'Camera Settings';
            app.MicronPixelEditFieldLabel = uilabel(app.CameraSettingsTab);
            app.MicronPixelEditFieldLabel.Text = 'Micron/Pixel';
            app.MicronPixelEditField = uieditfield(app.CameraSettingsTab, 'numeric');
            app.MicronPixelEditField.Limits = [0 Inf];
            app.FPSEditFieldLabel = uilabel(app.CameraSettingsTab);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.CameraSettingsTab, 'numeric');
            app.FPSEditField.Limits = [0 Inf];
            app.FPSEditField.ValueChangedFcn = createCallbackFcn(app, @FPSEditFieldValueChanged, true);
            app.PlotAxesSettingsButtonGroup = uibuttongroup(app.CameraSettingsTab);
            app.PlotAxesSettingsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotAxesSettingsButtonGroupSelectionChanged, true);
            app.PlotAxesSettingsButtonGroup.Title = 'Plot Axes Settings';
            app.PxFrameButton = uiradiobutton(app.PlotAxesSettingsButtonGroup);
            app.PxFrameButton.Text = 'Px/Frame';
            app.PxFrameButton.Value = true;
            app.MicronSecondsButton = uiradiobutton(app.PlotAxesSettingsButtonGroup);
            app.MicronSecondsButton.Text = 'Micron/Seconds';
            app.UpdateButton = uibutton(app.CameraSettingsTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.IMRTab = uitab(app.ControlPanelTabs);
            app.IMRTab.Title = 'IMR';
            app.ExportDataDirectlytoIMRButton = uibutton(app.IMRTab, 'push');
            app.ExportDataDirectlytoIMRButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDataDirectlytoIMRButtonPushed, true);
            app.ExportDataDirectlytoIMRButton.Text = 'Export Data Directly to IMR';
            app.NumberFourierTermstoExportEditFieldLabel = uilabel(app.IMRTab);
            app.NumberFourierTermstoExportEditFieldLabel.Text = 'Number Fourier Terms to Export';
            app.NumberFourierTermstoExportEditField = uieditfield(app.IMRTab, 'numeric');
            app.NumberFourierTermstoExportEditField.Limits = [1 Inf];
            app.NumberFourierTermstoExportEditField.ValueChangedFcn = createCallbackFcn(app, @NumberFourierTermstoExportEditFieldValueChanged, true);
            app.NumberFourierTermstoExportEditField.Value = 8;
            app.SaveIMRDatatoFileButton = uibutton(app.IMRTab, 'push');
            app.SaveIMRDatatoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIMRDatatoFileButtonPushed, true);
            app.SaveIMRDatatoFileButton.Text = {'Save IMR Data to File'; ''};
            app.CompileMultipleIMRDataSetsButton = uibutton(app.IMRTab, 'push');
            app.CompileMultipleIMRDataSetsButton.Text = 'Compile Multiple IMR Data Sets';
            app.IncludeCurrentAnalysisCheckBox = uicheckbox(app.IMRTab);
            app.IncludeCurrentAnalysisCheckBox.Text = 'Include Current Analysis';
            app.IncludeCurrentAnalysisCheckBox.Value = true;
            app.ThemesTab = uitab(app.ControlPanelTabs);
            app.ThemesTab.Title = 'Themes';
            app.OptionsButtonGroup = uibuttongroup(app.ThemesTab);
            app.OptionsButtonGroup.Title = 'Dity''s Curated Collection';
            app.DefaultButton = uiradiobutton(app.OptionsButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.NightButton = uiradiobutton(app.OptionsButtonGroup);
            app.NightButton.Text = 'Night';
            app.DarkButton = uiradiobutton(app.OptionsButtonGroup);
            app.DarkButton.Text = 'Dark';
            app.ApplyButton = uibutton(app.OptionsButtonGroup, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ArmyButton = uiradiobutton(app.OptionsButtonGroup);
            app.ArmyButton.Text = 'Army';
            app.LeatherButton = uiradiobutton(app.OptionsButtonGroup);
            app.LeatherButton.Text = 'Leather';
            app.WavesButton = uiradiobutton(app.OptionsButtonGroup);
            app.WavesButton.Text = 'Waves';
            app.CustomButton = uiradiobutton(app.OptionsButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.RePlotFourierDataButton = uibutton(app.ControlPanel, 'push');
            app.RePlotFourierDataButton.ButtonPushedFcn = createCallbackFcn(app, @RePlotFourierDataButtonPushed, true);
            app.RePlotFourierDataButton.Tooltip = {'F5'};
            app.RePlotFourierDataButton.Text = 'Re-Plot Fourier Data';
            app.ExporttoExcelButton = uibutton(app.ControlPanel, 'push');
            app.ExporttoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoExcelButtonPushed, true);
            app.ExporttoExcelButton.Tooltip = {'F7'};
            app.ExporttoExcelButton.Text = 'Export to Excel';
            app.MaskOverlayPreviewPanel = uipanel(app.UIFigure);
            app.MaskOverlayPreviewPanel.Title = 'Mask Overlay Preview';
            app.MainViewerPanel = uipanel(app.UIFigure);
            app.MainViewerPanel.Title = 'Main Viewer';
            app.TabGroup2 = uitabgroup(app.MainViewerPanel);
            app.ViewerTab = uitab(app.TabGroup2);
            app.ViewerTab.Title = 'Viewer';
            app.MainPlot = uiaxes(app.ViewerTab);
            title(app.MainPlot, '')
            xlabel(app.MainPlot, '')
            ylabel(app.MainPlot, '')
            app.MainPlot.YDir = 'reverse';
            app.MainPlot.XTick = [];
            app.MainPlot.YTick = [];
            app.PreviousFrameButton = uibutton(app.ViewerTab, 'push');
            app.PreviousFrameButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFrameButtonPushed, true);
            app.PreviousFrameButton.Tooltip = {'Left Arrow Key'};
            app.PreviousFrameButton.Text = 'Previous Frame';
            app.NextFrameButton = uibutton(app.ViewerTab, 'push');
            app.NextFrameButton.ButtonPushedFcn = createCallbackFcn(app, @NextFrameButtonPushed, true);
            app.NextFrameButton.Tooltip = {'Right Arrow Key'};
            app.NextFrameButton.Text = 'Next Frame';
            app.AreaLabel = uilabel(app.ViewerTab);
            app.AreaLabel.Text = 'Area: ';
            app.PerimeterLabel = uilabel(app.ViewerTab);
            app.PerimeterLabel.Text = 'Perimeter:';
            app.AverageRadiusLabel = uilabel(app.ViewerTab);
            app.AverageRadiusLabel.Text = 'Average Radius: ';
            app.CentroidLabel = uilabel(app.ViewerTab);
            app.CentroidLabel.Text = 'Centroid: ';
            app.FrameNumberSpinnerLabel = uilabel(app.ViewerTab);
            app.FrameNumberSpinnerLabel.Text = 'Frame Number: ';
            app.FrameNumberSpinner = uispinner(app.ViewerTab);
            app.FrameNumberSpinner.Limits = [1 180];
            app.FrameNumberSpinner.RoundFractionalValues = 'on';
            app.FrameNumberSpinner.ValueChangedFcn = createCallbackFcn(app, @FrameNumberSpinnerValueChanged, true);
            app.FrameNumberSpinner.Value = 1;
            app.EvolutionOverlayTab = uitab(app.TabGroup2);
            app.EvolutionOverlayTab.Title = 'Evolution Overlay';
            app.EvolutionPlot = uiaxes(app.EvolutionOverlayTab);
            title(app.EvolutionPlot, '')
            xlabel(app.EvolutionPlot, '')
            ylabel(app.EvolutionPlot, '')
            app.EvolutionPlot.YDir = 'reverse';
            app.EvolutionPlot.XTick = [];
            app.EvolutionPlot.YTick = [];
            app.ColorMap = uiimage(app.EvolutionOverlayTab);
            app.Frame1Label = uilabel(app.EvolutionOverlayTab);
            app.Frame1Label.Text = 'Frame 1';
            app.Frame180Label = uilabel(app.EvolutionOverlayTab);
            app.Frame180Label.Text = 'Frame 180';
            app.AnalysisResultsPanel = uipanel(app.UIFigure);
            app.AnalysisResultsPanel.Title = 'Analysis Results';
            app.TabGroup = uitabgroup(app.AnalysisResultsPanel);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '2D';
            app.AverageRadiusPanel = uipanel(app.DTab);
            app.AverageRadiusPanel.Title = 'Average Radius';
            app.RadiusPlot = uiaxes(app.AverageRadiusPanel);
            title(app.RadiusPlot, '')
            xlabel(app.RadiusPlot, 'Frame')
            ylabel(app.RadiusPlot, 'Pixels')
            app.PerimeterPanel = uipanel(app.DTab);
            app.PerimeterPanel.Title = 'Perimeter';
            app.PerimeterPlot = uiaxes(app.PerimeterPanel);
            title(app.PerimeterPlot, '')
            xlabel(app.PerimeterPlot, 'Frame')
            ylabel(app.PerimeterPlot, 'Pixels')
            app.AreaPanel = uipanel(app.DTab);
            app.AreaPanel.Title = 'Area';
            app.AreaPlot = uiaxes(app.AreaPanel);
            title(app.AreaPlot, '')
            xlabel(app.AreaPlot, 'Frame')
            ylabel(app.AreaPlot, 'Square Pixels')
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '3D';
            app.SurfaceAreaPanel = uipanel(app.DTab_2);
            app.SurfaceAreaPanel.Title = 'Surface Area';
            app.SurfaceAreaPlot = uiaxes(app.SurfaceAreaPanel);
            title(app.SurfaceAreaPlot, '')
            xlabel(app.SurfaceAreaPlot, 'Frame')
            ylabel(app.SurfaceAreaPlot, 'Square Pixels')
            app.VolumePanel = uipanel(app.DTab_2);
            app.VolumePanel.Title = 'Volume';
            app.VolumePlot = uiaxes(app.VolumePanel);
            title(app.VolumePlot, '')
            xlabel(app.VolumePlot, 'Frame')
            ylabel(app.VolumePlot, 'Cubic Pixels')
            app.CentroidTab = uitab(app.TabGroup);
            app.CentroidTab.Title = 'Centroid ';
            app.CentroidPlot = uiaxes(app.CentroidTab);
            title(app.CentroidPlot, '')
            xlabel(app.CentroidPlot, 'X')
            ylabel(app.CentroidPlot, 'Y')
            app.FourierFitDataTab = uitab(app.TabGroup);
            app.FourierFitDataTab.Title = 'Fourier Fit Data';
            app.AsphericityPlot = uiaxes(app.FourierFitDataTab);
            title(app.AsphericityPlot, '')
            xlabel(app.AsphericityPlot, 'Frame')
            ylabel(app.AsphericityPlot, 'sqrt(aN^2 + bN^2) / sqrt(a1^2 + b1^2)')
            app.FourierColorMap = uiimage(app.FourierFitDataTab);
            app.ndTermLabel = uilabel(app.FourierFitDataTab);
            app.ndTermLabel.Text = '2nd Term';
            app.LastTermLabel = uilabel(app.FourierFitDataTab);
            app.LastTermLabel.Text = 'Last Term';
            app.FourierDecompositionTab = uitab(app.TabGroup);
            app.FourierDecompositionTab.Title = 'Fourier Decomposition';
            app.DecompositionSettingsPanel = uipanel(app.FourierDecompositionTab);
            app.DecompositionSettingsPanel.Title = 'Decomposition Settings';
            app.TargetFrameEditFieldLabel = uilabel(app.DecompositionSettingsPanel);
            app.TargetFrameEditFieldLabel.Text = 'Target Frame ';
            app.TargetFrameEditField = uieditfield(app.DecompositionSettingsPanel, 'numeric');
            app.TargetFrameEditField.Limits = [1 Inf];
            app.TargetFrameEditField.Value = 1;
            app.TermstoDecomposeEditFieldLabel = uilabel(app.DecompositionSettingsPanel);
            app.TermstoDecomposeEditFieldLabel.Text = 'Terms to Decompose';
            app.TermstoDecomposeEditField = uieditfield(app.DecompositionSettingsPanel, 'numeric');
            app.TermstoDecomposeEditField.Limits = [1 Inf];
            app.TermstoDecomposeEditField.Value = 8;
            app.SortOrderButtonGroup = uibuttongroup(app.DecompositionSettingsPanel);
            app.SortOrderButtonGroup.Title = 'Sort Order';
            app.DescendingButton = uiradiobutton(app.SortOrderButtonGroup);
            app.DescendingButton.Text = 'Descending';
            app.DescendingButton.Value = true;
            app.AscendingButton = uiradiobutton(app.SortOrderButtonGroup);
            app.AscendingButton.Text = 'Ascending';
            app.MaintainColormapCheckBox = uicheckbox(app.DecompositionSettingsPanel);
            app.MaintainColormapCheckBox.Text = 'Maintain Colormap';
            app.MaintainColormapCheckBox.Value = true;
            app.DecomposeButton = uibutton(app.DecompositionSettingsPanel, 'push');
            app.DecomposeButton.ButtonPushedFcn = createCallbackFcn(app, @DecomposeButtonPushed, true);
            app.DecomposeButton.Text = 'Decompose';
            app.DecomposedPlotsPanel = uipanel(app.FourierDecompositionTab);
            app.DecomposedPlotsPanel.Title = 'Decomposed Plots';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InCA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end