classdef frameInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InCAFrameInspectorUIFigure  matlab.ui.Figure
        ThreeDViewer                matlab.ui.control.UIAxes
        TwoDViewer                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, image, mask, maskInformation, didFit, minArcLength, orientation)
            f = uiprogressdlg(app.InCAFrameInspectorUIFigure, 'Title', 'Please wait', 'Message', 'Generating plots...', 'Indeterminate',"on", "Icon",  "none");
            drawnow;
            InCAFrameInspectorUIFigureSizeChanged(app, 0)
            imshow(image, 'Parent', app.TwoDViewer);
            hold(app.TwoDViewer, 'on');
            for i = 1:2
                if i == 1
                    perimeterPoints = maskInformation.PerimeterPoints;
                    center = maskInformation.Centroid;
                    tracking = maskInformation.TrackingPoints;
                    plot(app.TwoDViewer, perimeterPoints(:, 1), perimeterPoints(:, 2), 'r', ...
                        center(1), center(2), 'b*',...
                        tracking(:, 1), tracking(:, 2), 'y*', 'LineWidth', 2, 'MarkerSize', 5);
                elseif i == 2
                    if didFit
                        fourier = maskInformation.FourierPoints;
                        plot(app.TwoDViewer, fourier(:, 1), fourier(:, 2), 'c.', 'MarkerSize', 5);
                        plot(app.TwoDViewer, maskInformation.xData, maskInformation.yData, '-m');
                        plot(app.TwoDViewer, maskInformation.FourierFitX.a0, maskInformation.FourierFitY.b0, '-g*', 'MarkerSize', 15)
                    end
                end
            end
            legend(app.TwoDViewer, 'Mask Perimeter', 'Centroid', 'Tracking Points', 'Fourier Fit Points', 'Fourier Fit', 'Fourier Fit Centroid', 'TextColor', 'white');
            hold(app.TwoDViewer, 'off');
            
            %% Three D Viewer 
            colormap(app.ThreeDViewer, plasma)
            translatedPoints = bubbleAnalysis.translatePerim(maskInformation.PerimeterPoints, maskInformation.Centroid);
            rotatedPoints = bubbleAnalysis.rotatePerim(translatedPoints, orientation);
            surfPoints = bubbleAnalysis.genSurface(rotatedPoints, minArcLength);
            D = delaunay(surfPoints);
            trimesh(D, surfPoints(:, 1), surfPoints(:, 2), surfPoints(:, 3), 'Parent', app.ThreeDViewer);
            axis(app.ThreeDViewer, 'equal');
            drawnow;
            close(f);
        end

        % Size changed function: InCAFrameInspectorUIFigure
        function InCAFrameInspectorUIFigureSizeChanged(app, event)
            position = app.InCAFrameInspectorUIFigure.Position;
            app.TwoDViewer.Position = [0, 0, position(3)/2, position(4)];
            app.ThreeDViewer.Position = [position(3)/2, 0, position(3)/2, position(4)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InCAFrameInspectorUIFigure = uifigure('Visible', 'off');
            app.InCAFrameInspectorUIFigure.Name = 'InCA Frame Inspector';
            app.InCAFrameInspectorUIFigure.SizeChangedFcn = createCallbackFcn(app, @InCAFrameInspectorUIFigureSizeChanged, true);
            app.ThreeDViewer = uiaxes(app.InCAFrameInspectorUIFigure);
            title(app.ThreeDViewer, '')
            xlabel(app.ThreeDViewer, '')
            ylabel(app.ThreeDViewer, '')
            app.ThreeDViewer.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ThreeDViewer.XTickLabel = '';
            app.ThreeDViewer.YTickLabel = '';
            app.TwoDViewer = uiaxes(app.InCAFrameInspectorUIFigure);
            title(app.TwoDViewer, '')
            xlabel(app.TwoDViewer, '')
            ylabel(app.TwoDViewer, '')
            app.TwoDViewer.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.TwoDViewer.XTickLabel = '';
            app.TwoDViewer.YTickLabel = '';
            % Show the figure after all components are created
            app.InCAFrameInspectorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frameInspector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InCAFrameInspectorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InCAFrameInspectorUIFigure)
        end
    end
end