classdef launchLogViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        ListBox   matlab.ui.control.ListBox
        TextArea  matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        names
        logs
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            f = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', 'Loading logs...', 'Indeterminate', 'on');
            currentDirectory = pwd;
            if contains(currentDirectory, "main")
                idcs = strfind(currentDirectory, filesep);
                parentDirectory = currentDirectory(1:idcs(end) - 1);  
                files = dir(parentDirectory + "\logs\");
            elseif contains(currentDirectory, "logs")
                files = dir;
            else
                files = dir('logs\');
            end
            app.names = {files.name};
            app.names = app.names(3:end);
            app.ListBox.Items = app.names;
            app.ListBox.Value = app.names{1};
            app.logs = logging.loadLogs(app.names);
            ListBoxValueChanged(app, 0);
            close(f)
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            app.ListBox.Position = [10, 10, position(3)./5, position(4) - 20];
            ListWidth = position(3)./5;
            app.TextArea.Position = [20 + ListWidth, 10, (position(3) - 30 - position(3)./5), position(4) - 20];
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            f = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', 'Loading log...', 'Indeterminate', 'on');
            value = app.ListBox.Value;
            displayText = app.logs(:, find(contains(app.names, value)));
            app.TextArea.Value = displayText;
            close(f)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'InCA Log Viewer';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = launchLogViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end